classdef ME2420_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        AddPointButton           matlab.ui.control.Button
        UndoButton               matlab.ui.control.Button
        AddShapeButton           matlab.ui.control.Button
        DoneButton               matlab.ui.control.Button
        CalculateButton          matlab.ui.control.Button
        ResultsTextAreaLabel     matlab.ui.control.Label
        ResultsTextArea          matlab.ui.control.TextArea
        MomentEditFieldLabel     matlab.ui.control.Label
        MomentEditField          matlab.ui.control.NumericEditField
        ORLabel                  matlab.ui.control.Label
        MaxStressEditFieldLabel  matlab.ui.control.Label
        MaxStressEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        PointArray = []; % Stores Points
        numPoints = 0;
        shapeNumber = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddPointButton
        function OnClick(app, event)
            if (app.numPoints == 3)
                app.AddPointButton.Enable = 'off';
            end
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            push = [x; y; app.shapeNumber];
            app.PointArray = horzcat(app.PointArray, push);
            app.numPoints = app.numPoints + 1;
            hold(app.UIAxes, 'on');
            for i = 1:size(app.PointArray, 2)
                plot(app.UIAxes, app.PointArray(1,i), app.PointArray(2, i), 'r*');
            end
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: UndoButton
        function OnClick2(app, event)
            if size (app.PointArray, 2) ~= 0
                app.PointArray(:, size(app.PointArray, 2)) = [];
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                for i = 1:size(app.PointArray, 2)
                    plot(app.UIAxes, app.PointArray(1,i), app.PointArray(2, i), 'r*');
                end
                hold(app.UIAxes, 'off');
                app.numPoints = app.numPoints - 1;
                if (app.numPoints < 3)
                    app.AddPointButton.Enable = 'on';
                end
            end
        end

        % Button pushed function: AddShapeButton
        function AddShape(app, event)
            % Allow User To Enter Points
            app.AddPointButton.Enable = 'on';
            app.UndoButton.Enable = 'on';
            app.DoneButton.Enable = 'on';
            app.shapeNumber = app.shapeNumber + 1;
            app.numPoints = 0;
        end

        % Button pushed function: DoneButton
        function Done(app, event)
            app.AddPointButton.Enable = 'off';
            app.UndoButton.Enable = 'off';
            app.DoneButton.Enable = 'off';
            
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            for i = 1:4:(size(app.PointArray, 2) - 1)
                plot(app.UIAxes, [app.PointArray(1, i), app.PointArray(1, i+1)], [app.PointArray(2,i), app.PointArray(2,i+1)], 'black');
                plot (app.UIAxes, [app.PointArray(1,i+1), app.PointArray(1,i+2)], [app.PointArray(2,i+1), app.PointArray(2,i+2)], 'black');
                plot (app.UIAxes, [app.PointArray(1,i+2), app.PointArray(1,i+3)], [app.PointArray(2,i+2), app.PointArray(2,i+3)], 'black');
                plot (app.UIAxes, [app.PointArray(1,i+3), app.PointArray(1,i)], [app.PointArray(2,i+3), app.PointArray(2,i)], 'black');
            end
            plot(app.UIAxes, [app.PointArray(1, size(app.PointArray, 2)), app.PointArray(1, size(app.PointArray, 2) - 3)], [app.PointArray(2, size(app.PointArray, 2)), app.PointArray(2,size(app.PointArray, 2) - 3)], 'black');
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: CalculateButton
        function Calculate(app, event)
            % User is done inputing shapes, calculate different variables
            
            j = 1;
            totalArea = 0;
            momentOfInertia = 0;
            neutralAxisNumerator = 0;
            areaDimensions = [];
            for i = 1:app.PointArray(3, size(app.PointArray, 2)) % Number of Shapes
                % Gets Height and Width of Rectangle
                width = app.PointArray(1, j + 2) - app.PointArray(1, j);
                height = app.PointArray(2, j + 2) - app.PointArray(2, j);
                ybar = (height / 2) + min(app.PointArray(2, j + 2), app.PointArray(2, j));
                lower = min(app.PointArray(2, j + 2), app.PointArray(2, j));
                upper = max(app.PointArray(2, j + 2), app.PointArray(2, j));
                while (j < size(app.PointArray, 2) && app.PointArray(3, j) == i)
                    j = j + 1; % Stores Index of Points
                end
                

                areaDimensions = horzcat(areaDimensions, [width;height;ybar;lower;upper]);
            end
            
            % Finds Neutral Axis and Area
            for i = 1:size(areaDimensions, 2)
                neutralAxisNumerator = neutralAxisNumerator + areaDimensions(1, i) * areaDimensions(2, i) * areaDimensions(3, i);
                totalArea = totalArea + areaDimensions(1, i) * areaDimensions(2, i);
            end
            
            % Finds Moment of Inertia
            for i = 1:size(areaDimensions, 2)
                momentOfInertia = momentOfInertia + 1/12 * areaDimensions(1, i) * areaDimensions(2, i) ^ 3 + areaDimensions(1, i) * areaDimensions(2, i) * (neutralAxisNumerator / totalArea - areaDimensions(3, i)) ^ 2;
            end
            
            % Finds Forces or Moment
            maxStress = 0;
            forceArray = [];
            results = {"Y Bar: " + neutralAxisNumerator / totalArea; "I: " + momentOfInertia};
            
            if (app.MomentEditField.Value ~= 0)
                % Calculates Forces and Finds Max Moment
                for i = 1:size(areaDimensions, 2)
                    s1 = app.MomentEditField.Value * (areaDimensions(4, i) - neutralAxisNumerator / totalArea) / momentOfInertia;
                    s2 = app.MomentEditField.Value * (areaDimensions(5, i) - neutralAxisNumerator / totalArea) / momentOfInertia;
                    if (s1 > maxStress)
                        maxStress = s1;
                    end
                    if (s2 > maxStress)
                        maxStress = s2;
                    end
                    
                    forceArray = horzcat(forceArray, 0.5 * (s1 + s2) * areaDimensions(1, i) * areaDimensions(2, i));
                    
                end
                
                results(3, 1) = {"Max Stress: " + maxStress};
                for i = 1:size(forceArray, 2)
                    results(3+i, 1) = {"F" + i + ": " + forceArray(1, i)};
                end
            elseif (app.MaxStressEditField ~= 0)
                moment = inf;
                
                for i = 1:size(areaDimensions, 2)
                    m1 = app.MaxStressEditField.Value * momentOfInertia / abs((areaDimensions(4, i) - neutralAxisNumerator / totalArea));
                    m2 = app.MaxStressEditField.Value * momentOfInertia / abs((areaDimensions(5, i) - neutralAxisNumerator / totalArea));
                    
                    if (m1 < moment)
                        moment = m1;
                    end
                    
                    if (m2 < moment)
                        moment = m2;
                    end
                    
                end
                
                results(3, 1) = {"Moment: " + moment};
                
            end


            app.ResultsTextArea.Value = string(results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Current Cross Section')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = {'X'; ''};
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @OnClick, true);
            app.AddPointButton.Enable = 'off';
            app.AddPointButton.Text = 'Add Point';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @OnClick2, true);
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = 'Undo';
            app.AddShapeButton = uibutton(app.UIFigure, 'push');
            app.AddShapeButton.ButtonPushedFcn = createCallbackFcn(app, @AddShape, true);
            app.AddShapeButton.Text = 'Add Shape';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @Done, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate, true);
            app.CalculateButton.Text = 'Calculate!';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.MomentEditFieldLabel = uilabel(app.UIFigure);
            app.MomentEditFieldLabel.Text = 'Moment';
            app.MomentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.MaxStressEditFieldLabel = uilabel(app.UIFigure);
            app.MaxStressEditFieldLabel.Text = 'Max Stress';
            app.MaxStressEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ME2420_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end